classdef afmxyznanoholeanalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        DoneButton_2           matlab.ui.control.Button
        DrawRectangleButton    matlab.ui.control.Button
        EnterAFMxyzfileButton  matlab.ui.control.Button
        UndoButton             matlab.ui.control.Button
        DoneButton             matlab.ui.control.Button
        HAxes                  matlab.ui.control.UIAxes
        VAxes                  matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end


    properties (Access = private)
        full_file_path
        drawnRectangles
        Xvec
        Yvec
        Zgrid

        % --- Point selection properties ---
        H_profile_X
        H_profile_Z
        V_profile_Y
        V_profile_Z

        H_hoverPoint
        V_hoverPoint

        H_selectedPoints     % Nx2 array of [x z]
        V_selectedPoints     % Nx2 array of [y z]
        H_selectedMarkers    % cell of graphics handles
        V_selectedMarkers

        H_pointsNeeded
        V_pointsNeeded

        deleteToleranceFactor

        H_interp_x1
        H_interp_z1
        H_interp_x2
        H_interp_z2

        H_interp_x3
        H_interp_z3
        H_interp_x4
        H_interp_z4

        H_interp_x
        H_interp_z
        V_interp_y
        V_interp_z

        V_interp_y1
        V_interp_z1
        V_interp_y2
        V_interp_z2

        V_interp_y3
        V_interp_z3
        V_interp_y4
        V_interp_z4

        H_boundaryIndices = [];
        V_boundaryIndices = [];
        H_boundaryMarkers
        V_boundaryMarkers

        H_hoverBoundary
        V_hoverBoundary

        H_interpLines
        V_interpLines

        minZval;
        Diameters_COM_X = []    % Horizontal COM diameter history
        Diameters_COM_Y = []    % Vertical COM diameter history
        Diameters_Peak_X = []   % Horizontal peak-to-peak diameter history
        Diameters_Peak_Y = []   % Vertical peak-to-peak diameter history
        Depth;
        rectpositions;
        PointSelectionHistory = {};     % For points (H_point, V_point)
        BoundarySelectionHistory = {};  % For boundaries (H_boundary, V_boundary)

    end


    methods (Access = private)

        function [peakLeft, peakRight] = computePeakDiameter_H(app)

            % Left ring peak
            [~, idxL] = max(app.H_interp_z1);
            peakLeft = app.H_interp_x1(idxL);

            % Right ring peak
            [~, idxR] = max(app.H_interp_z2);
            peakRight = app.H_interp_x2(idxR);
        end

        function [peakLeft, peakRight] = computePeakDiameter_V(app)

            % Left ring peak
            [~, idxL] = max(app.V_interp_z1);
            peakLeft = app.V_interp_y1(idxL);

            % Right ring peak
            [~, idxR] = max(app.V_interp_z2);
            peakRight = app.V_interp_y2(idxR);
        end


        function [comLeftX, comRightX] = compute_COM_H(app)

            if numel(app.H_boundaryIndices) ~= 2
                error('Need exactly 2 H boundary indices.');
            end

            idxA = app.H_boundaryIndices(1);
            idxB = app.H_boundaryIndices(2);

            N1 = numel(app.H_interp_x1);
            N2 = numel(app.H_interp_x2);

            if idxA <= N1
                leftStart = 1;
                leftEnd   = idxA;
            else
                error('Left boundary must be inside left ring region.');
            end

            if idxB <= N2
                rightStart = idxB;
                rightEnd   = N2;
            else
                error('Right boundary must be inside right ring region.');
            end

            xL = app.H_interp_x1(leftStart:leftEnd);
            zL = abs(app.H_interp_z1(leftStart:leftEnd)-app.H_interp_z1(leftStart));
            comLeftX = sum(zL .* xL) / sum(zL);

            xR = app.H_interp_x2(rightStart:rightEnd);
            zR = abs(app.H_interp_z2(rightStart:rightEnd)-app.H_interp_z2(rightEnd));
            comRightX = sum(zR .* xR) / sum(zR);

        end


        function [comLeftY, comRightY] = compute_COM_V(app)

            if numel(app.V_boundaryIndices) ~= 2
                error('Need exactly 2 V boundary indices.');
            end

            idxA = app.V_boundaryIndices(1);
            idxB = app.V_boundaryIndices(2);

            N1 = numel(app.V_interp_y1);
            N2 = numel(app.V_interp_y2);

            if idxA <= N1
                leftStart = 1;
                leftEnd   = idxA;
            else
                error('Left V boundary must be inside left ring.');
            end

            if idxB <= N1
                rightStart = idxB;
                rightEnd   = N2;
            else
                error('Right V boundary must be inside right ring.');
            end

            yL = app.V_interp_y1(leftStart:leftEnd);
            zL = abs(app.V_interp_z1(leftStart:leftEnd) - app.V_interp_z1(leftStart));
            comLeftY = sum(zL .* yL) / sum(zL);

            yR = app.V_interp_y2(rightStart:rightEnd);
            zR = abs(app.V_interp_z2(rightStart:rightEnd) - app.V_interp_z2(rightEnd));
            comRightY = sum(zR .* yR) / sum(zR);

        end


        function start_boundary_selection(app, Hx, Hz, Vy, Vz)

            app.H_interp_x = Hx(:);
            app.H_interp_z = Hz(:);
            app.V_interp_y = Vy(:);
            app.V_interp_z = Vz(:);

            app.H_boundaryIndices = [];
            app.V_boundaryIndices = [];

            app.H_boundaryMarkers = {};
            app.V_boundaryMarkers = {};

            app.H_hoverBoundary = [];
            app.V_hoverBoundary = [];

            figureHandle = ancestor(app.UIAxes, 'figure');
            figureHandle.WindowButtonMotionFcn = @(~,~) app.boundaryHover();
            figureHandle.WindowButtonDownFcn   = @(~,~) app.boundaryClick();
        end

        function boundaryHover(app)

            fig = ancestor(app.UIAxes,'figure');
            obj = hittest(fig);
            ax = ancestor(obj,'axes');

            if isequal(ax, app.HAxes)
                cp = app.HAxes.CurrentPoint;
                app.highlightBoundary_H(cp(1,1), cp(1,2));
                if ~isempty(app.V_hoverBoundary), delete(app.V_hoverBoundary); app.V_hoverBoundary = []; end

            elseif isequal(ax, app.VAxes)
                cp = app.VAxes.CurrentPoint;
                app.highlightBoundary_V(cp(1,1), cp(1,2));
                if ~isempty(app.H_hoverBoundary), delete(app.H_hoverBoundary); app.H_hoverBoundary = []; end

            else
                if ~isempty(app.H_hoverBoundary), delete(app.H_hoverBoundary); app.H_hoverBoundary = []; end
                if ~isempty(app.V_hoverBoundary), delete(app.V_hoverBoundary); app.V_hoverBoundary = []; end
            end
        end

        function highlightBoundary_H(app, x, z)

            % Compute distance to left segment points
            d1 = sqrt((app.H_interp_x1 - x).^2 + (app.H_interp_z1 - z).^2);
            [minD1, idx1] = min(d1);

            % Compute distance to right segment points
            d2 = sqrt((app.H_interp_x2 - x).^2 + (app.H_interp_z2 - z).^2);
            [minD2, idx2] = min(d2);

            % Decide which segment is closer
            if minD1 < minD2
                selected_x = app.H_interp_x1(idx1);
                selected_z = app.H_interp_z1(idx1);
            else
                selected_x = app.H_interp_x2(idx2);
                selected_z = app.H_interp_z2(idx2);
            end

            % Delete previous hover marker if exists
            if ~isempty(app.H_hoverBoundary) && isvalid(app.H_hoverBoundary)
                delete(app.H_hoverBoundary);
            end

            % Draw red hover circle on closest point
            app.H_hoverBoundary = line(app.HAxes, selected_x, selected_z, ...
                'Marker','o','MarkerSize',10,'Color','red','LineWidth',1.5,'HitTest','off');
        end


        function highlightBoundary_V(app, y, z)

            d = sqrt((app.V_interp_y - y).^2 + (app.V_interp_z - z).^2);
            [~, idx] = min(d);

            if ~isempty(app.V_hoverBoundary) && isvalid(app.V_hoverBoundary)
                delete(app.V_hoverBoundary);
            end

            app.V_hoverBoundary = line(app.VAxes, ...
                app.V_interp_y(idx), app.V_interp_z(idx), ...
                'Marker','o','MarkerSize',10,'Color','red','LineWidth',1.5,'HitTest','off');
        end

        function boundaryClick(app)

            fig = ancestor(app.UIAxes,'figure');
            sel = fig.SelectionType;
            clickedObj = hittest(fig);
            ax = ancestor(clickedObj,'axes');

            if strcmp(sel, 'normal')     % CLICK → select
                if isequal(ax, app.HAxes)
                    app.selectBoundary_H();
                elseif isequal(ax, app.VAxes)
                    app.selectBoundary_V();
                end

            elseif strcmp(sel, 'open')   % DOUBLE CLICK → delete
                if isequal(ax, app.HAxes)
                    cp = app.HAxes.CurrentPoint;
                    app.deleteBoundary_H(cp(1,1), cp(1,2));
                elseif isequal(ax, app.VAxes)
                    cp = app.VAxes.CurrentPoint;
                    app.deleteBoundary_V(cp(1,1), cp(1,2));
                end
            end

            % When two boundaries chosen → lock
            if numel(app.H_boundaryIndices) == 2 && numel(app.V_boundaryIndices) == 2
                fig.WindowButtonMotionFcn = '';
                fig.WindowButtonDownFcn = '';
            end
        end

        function selectBoundary_H(app)
            if isempty(app.H_hoverBoundary), return; end
            if numel(app.H_boundaryIndices) >= 2
                return;
            end

            % Distances to left segment points
            d1 = sqrt((app.H_interp_x1 - app.H_hoverBoundary.XData).^2 + ...
                (app.H_interp_z1 - app.H_hoverBoundary.YData).^2);

            % Distances to right segment points
            d2 = sqrt((app.H_interp_x2 - app.H_hoverBoundary.XData).^2 + ...
                (app.H_interp_z2 - app.H_hoverBoundary.YData).^2);

            [minD1, idx1] = min(d1);
            [minD2, idx2] = min(d2);

            if minD1 < minD2
                idx = idx1;
                x = app.H_interp_x1(idx);
                z = app.H_interp_z1(idx);
            else
                idx = idx2 + length(app.H_interp_x1);  % offset for second segment
                x = app.H_interp_x2(idx2);
                z = app.H_interp_z2(idx2);
            end

            app.H_boundaryIndices(end+1) = idx;

            h = line(app.HAxes, x, z, 'Marker','o','MarkerSize',10, 'Color','green', 'LineWidth',1.5);

            app.H_boundaryMarkers{end+1} = h;
            app.BoundarySelectionHistory{end+1} = struct('Type', 'H_boundary', 'Index', numel(app.H_boundaryIndices));

        end


        function selectBoundary_V(app)
            if isempty(app.V_hoverBoundary), return; end
            if numel(app.V_boundaryIndices) >= 2
                return;
            end

            % Distances to segment 1 points
            d1 = sqrt((app.V_interp_y1 - app.V_hoverBoundary.XData).^2 + ...
                (app.V_interp_z1 - app.V_hoverBoundary.YData).^2);

            % Distances to segment 2 points
            d2 = sqrt((app.V_interp_y2 - app.V_hoverBoundary.XData).^2 + ...
                (app.V_interp_z2 - app.V_hoverBoundary.YData).^2);

            [minD1, idx1] = min(d1);
            [minD2, idx2] = min(d2);

            if minD1 < minD2
                idx = idx1;
                x = app.V_interp_y1(idx);
                z = app.V_interp_z1(idx);
                % Adjust index for combined indexing if needed
            else
                idx = idx2 + length(app.V_interp_y1);  % offset for second segment
                x = app.V_interp_y2(idx2);
                z = app.V_interp_z2(idx2);
            end

            app.V_boundaryIndices(end+1) = idx;

            h = line(app.VAxes, x, z, 'Marker','o','MarkerSize',10,'Color','green','LineWidth',1.5);

            app.V_boundaryMarkers{end+1} = h;
            app.BoundarySelectionHistory{end+1} = struct('Type', 'V_boundary', 'Index', numel(app.V_boundaryIndices));

        end


        function deleteBoundary_H(app, x, z)

            if isempty(app.H_boundaryIndices), return; end

            idxList = app.H_boundaryIndices;
            pts = [app.H_interp_x(idxList), app.H_interp_z(idxList)];

            d = sqrt((pts(:,1)-x).^2 + (pts(:,2)-z).^2);
            [~, k] = min(d);

            delete(app.H_boundaryMarkers{k});
            app.H_boundaryMarkers(k) = [];
            app.H_boundaryIndices(k) = [];

        end

        function deleteBoundary_V(app, x, z)

            if isempty(app.V_boundaryIndices), return; end

            idxList = app.V_boundaryIndices;
            pts = [app.V_interp_y(idxList), app.V_interp_z(idxList)];

            d = sqrt((pts(:,1)-x).^2 + (pts(:,2)-z).^2);
            [~, k] = min(d);

            delete(app.V_boundaryMarkers{k});
            app.V_boundaryMarkers(k) = [];
            app.V_boundaryIndices(k) = [];

        end


        function [Hx1, Hz1, Hx2, Hz2, Vy1, Vz1, Vy2, Vz2] = build_interpolations(app)

            %===========================
            % HORIZONTAL AXIS
            %===========================
            Hpts = app.H_selectedPoints;
            if size(Hpts,1) < 6
            end

            % Sort by X (just to be safe)
            [~, idxH] = sort(Hpts(:,1));
            H_sorted = Hpts(idxH, :);

            % Define ranges for two segments (1-3 and 4-6 selected points)
            range1_start = H_sorted(1,1);
            range1_end   = H_sorted(3,1);

            range2_start = H_sorted(4,1);
            range2_end   = H_sorted(6,1);

            range3_start = H_sorted(2,1);
            range3_end   = H_sorted(3,1);

            range4_start = H_sorted(4,1);
            range4_end   = H_sorted(5,1);

            % Find indices in the original full profile within these ranges
            idx_range1 = find(app.H_profile_X >= range1_start & app.H_profile_X <= range1_end);
            idx_range2 = find(app.H_profile_X >= range2_start & app.H_profile_X <= range2_end);
            idx_range3 = find(app.H_profile_X >= range3_start & app.H_profile_X <= range3_end);
            idx_range4 = find(app.H_profile_X >= range4_start & app.H_profile_X <= range4_end);

            % Extract original data in these ranges
            Hx1_data = app.H_profile_X(idx_range1);
            Hz1_data = app.H_profile_Z(idx_range1);

            Hx2_data = app.H_profile_X(idx_range2);
            Hz2_data = app.H_profile_Z(idx_range2);

            Hx3_data = app.H_profile_X(idx_range3);
            Hz3_data = app.H_profile_Z(idx_range3);

            Hx4_data = app.H_profile_X(idx_range4);
            Hz4_data = app.H_profile_Z(idx_range4);

            % Interpolate to 500 points in each range
            Hx1 = linspace(range1_start, range1_end, 1000);
            Hz1 = interp1(Hx1_data, Hz1_data, Hx1, 'linear');

            Hx2 = linspace(range2_start, range2_end, 1000);
            Hz2 = interp1(Hx2_data, Hz2_data, Hx2, 'linear');

            Hx3 = linspace(range3_start, range3_end, 1000);
            Hz3 = interp1(Hx3_data, Hz3_data, Hx3, 'linear');

            Hx4 = linspace(range4_start, range4_end, 1000);
            Hz4 = interp1(Hx4_data, Hz4_data, Hx4, 'linear');


            Vpts = app.V_selectedPoints;
            if size(Vpts,1) < 6
            end

            % Sort by Y
            [~, idxV] = sort(Vpts(:,1));
            V_sorted = Vpts(idxV, :);

            % Two ranges for vertical axis (1-3 and 4-6 points)
            vrange1_start = V_sorted(1,1);
            vrange1_end   = V_sorted(3,1);

            vrange2_start = V_sorted(4,1);
            vrange2_end   = V_sorted(6,1);

            vrange3_start = V_sorted(2,1);
            vrange3_end   = V_sorted(3,1);

            vrange4_start = V_sorted(4,1);
            vrange4_end   = V_sorted(5,1);

            idx_vrange1 = find(app.V_profile_Y >= vrange1_start & app.V_profile_Y <= vrange1_end);
            idx_vrange2 = find(app.V_profile_Y >= vrange2_start & app.V_profile_Y <= vrange2_end);

            idx_vrange3 = find(app.V_profile_Y >= vrange3_start & app.V_profile_Y <= vrange3_end);
            idx_vrange4 = find(app.V_profile_Y >= vrange4_start & app.V_profile_Y <= vrange4_end);

            Vy1_data = app.V_profile_Y(idx_vrange1);
            Vz1_data = app.V_profile_Z(idx_vrange1);

            Vy2_data = app.V_profile_Y(idx_vrange2);
            Vz2_data = app.V_profile_Z(idx_vrange2);

            Vy3_data = app.V_profile_Y(idx_vrange3);
            Vz3_data = app.V_profile_Z(idx_vrange3);

            Vy4_data = app.V_profile_Y(idx_vrange4);
            Vz4_data = app.V_profile_Z(idx_vrange4);

            Vy1 = linspace(vrange1_start, vrange1_end, 1000);
            Vz1 = interp1(Vy1_data, Vz1_data, Vy1, 'linear');

            Vy2 = linspace(vrange2_start, vrange2_end, 1000);
            Vz2 = interp1(Vy2_data, Vz2_data, Vy2, 'linear');

            Vy3 = linspace(vrange3_start, vrange3_end, 1000);
            Vz3 = interp1(Vy3_data, Vz3_data, Vy3, 'linear');

            Vy4 = linspace(vrange4_start, vrange4_end, 1000);
            Vz4 = interp1(Vy4_data, Vz4_data, Vy4, 'linear');

            app.H_interp_x1 = Hx1;
            app.H_interp_z1 = Hz1;
            app.H_interp_x2 = Hx2;
            app.H_interp_z2 = Hz2;

            app.V_interp_y1 = Vy1;
            app.V_interp_z1 = Vz1;
            app.V_interp_y2 = Vy2;
            app.V_interp_z2 = Vz2;

            app.H_interp_x3 = Hx3;
            app.H_interp_z3 = Hz3;
            app.H_interp_x4 = Hx4;
            app.H_interp_z4 = Hz4;

            app.V_interp_y3 = Vy3;
            app.V_interp_z3 = Vz3;
            app.V_interp_y4 = Vy4;
            app.V_interp_z4 = Vz4;

            % 
            % hold(app.HAxes, 'on');
            % plot(app.HAxes, Hx1, Hz1, 'b-', 'LineWidth', 1.5, 'DisplayName', 'H Left Ring');
            % plot(app.HAxes, Hx2, Hz2, 'r-', 'LineWidth', 1.5, 'DisplayName', 'H Right Ring');
            % title(app.HAxes, "Select 2 indices - [upperboundary for left ring, lower boundary for right ring]")
            % hold(app.HAxes, 'off');
            % 
            % hold(app.VAxes, 'on');
            % plot(app.VAxes, Vy1, Vz1, 'b-', 'LineWidth', 1.5, 'DisplayName', 'V Left Ring');
            % plot(app.VAxes, Vy2, Vz2, 'r-', 'LineWidth', 1.5, 'DisplayName', 'V Right Ring');
            % title(app.VAxes, "Select 2 indices - [upperboundary for left ring, lower boundary for right ring]")
            % hold(app.VAxes, 'off');
            % 
            % % Fix axes limits to avoid shifting during drag/interaction
            % xlim(app.HAxes, [min(app.H_profile_X), max(app.H_profile_X)]);
            % ylim(app.HAxes, [min(app.H_profile_Z), max(app.H_profile_Z)]);
            % 
            % xlim(app.VAxes, [min(app.V_profile_Y), max(app.V_profile_Y)]);
            % ylim(app.VAxes, [min(app.V_profile_Z), max(app.V_profile_Z)]);
            % 
            % app.H_interp_x = [app.H_interp_x1, app.H_interp_x2];
            % app.H_interp_z = [app.H_interp_z1, app.H_interp_z2];
            % 
            % app.V_interp_y = [app.V_interp_y1, app.V_interp_y2];
            % app.V_interp_z = [app.V_interp_z1, app.V_interp_z2];


        end


        function updateInterpolationPlots(app)

            % Get interpolated segments
            [Hx1, Hz1, Hx2, Hz2, Vy1, Vz1, Vy2, Vz2] = app.build_interpolations();

            % Hold on so overlays appear on existing plots
            hold(app.HAxes, 'on');
            hold(app.VAxes, 'on');

            % Clear previous interpolation lines if you saved their handles (optional)
            if isfield(app, 'H_interpLines')
                if ~isempty(app.H_interpLines)
                    delete(app.H_interpLines(ishandle(app.H_interpLines)));
                end
            end
            if isfield(app, 'V_interpLines')
                if ~isempty(app.V_interpLines)
                    delete(app.V_interpLines(ishandle(app.V_interpLines)));
                end
            end

            % Plot the two horizontal interpolated segments
            h1 = plot(app.HAxes, Hx1, Hz1, 'r-', 'LineWidth', 2);
            h2 = plot(app.HAxes, Hx2, Hz2, 'r-', 'LineWidth', 2);

            % Plot the two vertical interpolated segments
            v1 = plot(app.VAxes, Vy1, Vz1, 'r-', 'LineWidth', 2);
            v2 = plot(app.VAxes, Vy2, Vz2, 'r-', 'LineWidth', 2);

            % Store handles for future deletion if needed
            app.H_interpLines = [h1, h2];
            app.V_interpLines = [v1, v2];

            hold(app.HAxes, 'off');
            hold(app.VAxes, 'off');
        end


        function start_point_selection(app, HX, HZ, VY, VZ)

            app.H_profile_X = HX(:);
            app.H_profile_Z = HZ(:);
            app.V_profile_Y = VY(:);
            app.V_profile_Z = VZ(:);

            app.H_selectedPoints = zeros(0,2);
            app.V_selectedPoints = zeros(0,2);
            app.H_selectedMarkers = {};
            app.V_selectedMarkers = {};
            app.H_pointsNeeded = 6;
            app.V_pointsNeeded = 6;

            if ~isempty(app.H_hoverPoint) && isvalid(app.H_hoverPoint), delete(app.H_hoverPoint); end
            if ~isempty(app.V_hoverPoint) && isvalid(app.V_hoverPoint), delete(app.V_hoverPoint); end
            app.H_hoverPoint = [];
            app.V_hoverPoint = [];

            fig = ancestor(app.UIAxes, 'figure');
            fig.WindowButtonMotionFcn = @(~,~) app.onMouseMove();
            fig.WindowButtonDownFcn   = @(~,~) app.onMouseClick();
        end

        function onMouseMove(app)
            fig = ancestor(app.UIAxes,'figure');
            obj = hittest(fig);
            ax = ancestor(obj, 'axes');

            if isequal(ax, app.HAxes)
                cp = app.HAxes.CurrentPoint;
                x = cp(1,1); y = cp(1,2);
                app.highlight_nearest_H(x,y);
                if ~isempty(app.V_hoverPoint) && isvalid(app.V_hoverPoint)
                    delete(app.V_hoverPoint); app.V_hoverPoint = [];
                end

            elseif isequal(ax, app.VAxes)
                cp = app.VAxes.CurrentPoint;
                x = cp(1,1); y = cp(1,2);
                app.highlight_nearest_V(x,y);

                if ~isempty(app.H_hoverPoint) && isvalid(app.H_hoverPoint)
                    delete(app.H_hoverPoint); app.H_hoverPoint = [];
                end

            else

                if ~isempty(app.H_hoverPoint) && isvalid(app.H_hoverPoint)
                    delete(app.H_hoverPoint); app.H_hoverPoint = [];
                end
                if ~isempty(app.V_hoverPoint) && isvalid(app.V_hoverPoint)
                    delete(app.V_hoverPoint); app.V_hoverPoint = [];
                end
            end
        end

        function highlight_nearest_H(app, x, y)
            % guard
            if isempty(app.H_profile_X), return; end

            d = sqrt((app.H_profile_X - x).^2 + (app.H_profile_Z - y).^2);
            [~, idx] = min(d);


            if ~isempty(app.H_hoverPoint) && isvalid(app.H_hoverPoint)
                delete(app.H_hoverPoint);
            end

            app.H_hoverPoint = line(app.HAxes, app.H_profile_X(idx), app.H_profile_Z(idx), ...
                'Marker','o','MarkerSize',10,'LineWidth',1.5,'Color','red','HitTest','off');
        end

        function highlight_nearest_V(app, x, y)
            if isempty(app.V_profile_Y), return; end

            d = sqrt((app.V_profile_Y - x).^2 + (app.V_profile_Z - y).^2);
            [~, idx] = min(d);

            if ~isempty(app.V_hoverPoint) && isvalid(app.V_hoverPoint)
                delete(app.V_hoverPoint);
            end

            app.V_hoverPoint = line(app.VAxes, app.V_profile_Y(idx), app.V_profile_Z(idx), ...
                'Marker','o','MarkerSize',10,'LineWidth',1.5,'Color','red','HitTest','off');
        end

        function onMouseClick(app)
            fig = ancestor(app.UIAxes,'figure');

            sel = fig.SelectionType;

            clickedObj = hittest(fig);
            clickedAxes = ancestor(clickedObj, 'axes');

            if strcmp(sel, 'normal')
                if isequal(clickedAxes, app.HAxes)
                    app.attempt_select_H();
                elseif isequal(clickedAxes, app.VAxes)
                    app.attempt_select_V();
                end

            elseif strcmp(sel, 'open')

                if isequal(clickedAxes, app.HAxes)
                    cp = app.HAxes.CurrentPoint;
                    app.deleteClosest_H(cp(1,1), cp(1,2));
                elseif isequal(clickedAxes, app.VAxes)
                    cp = app.VAxes.CurrentPoint;
                    app.deleteClosest_V(cp(1,1), cp(1,2));
                else

                end
            end

            if size(app.H_selectedPoints,1) >= 6 && size(app.V_selectedPoints,1) >= 6
                fig.WindowButtonMotionFcn = '';
                fig.WindowButtonDownFcn = '';
                
            end
        end

        function attempt_select_H(app)
            % must have a hover point
            if isempty(app.H_hoverPoint) || ~isvalid(app.H_hoverPoint)
                return;
            end
            if size(app.H_selectedPoints,1) >= app.H_pointsNeeded
                return;
            end

            x = app.H_hoverPoint.XData;
            z = app.H_hoverPoint.YData;
            pt = [x, z];

            % prevent duplicate identical selection
            if ~isempty(app.H_selectedPoints)
                if any(all(abs(app.H_selectedPoints - pt) < eps,2))
                    % already selected exactly
                    return;
                end
            end

            marker = line(app.HAxes, x, z, 'Marker','o','MarkerSize',9, ...
                'Color','green','LineWidth',1.5,'HitTest','off');

            app.H_selectedPoints(end+1,:) = pt;
            app.H_selectedMarkers{end+1} = marker;
            app.PointSelectionHistory{end+1} = struct('Type', 'H_point', 'Index', size(app.H_selectedPoints, 1));

        end

        function attempt_select_V(app)
            if isempty(app.V_hoverPoint) || ~isvalid(app.V_hoverPoint)
                return;
            end
            if size(app.V_selectedPoints,1) >= app.V_pointsNeeded
                return;
            end

            x = app.V_hoverPoint.XData;
            z = app.V_hoverPoint.YData;
            pt = [x, z];

            if ~isempty(app.V_selectedPoints)
                if any(all(abs(app.V_selectedPoints - pt) < eps,2))
                    return;
                end
            end

            marker = line(app.VAxes, x, z, 'Marker','o','MarkerSize',9, ...
                'Color','green','LineWidth',1.5,'HitTest','off');

            app.V_selectedPoints(end+1,:) = pt;
            app.V_selectedMarkers{end+1} = marker;
            app.PointSelectionHistory{end+1} = struct('Type', 'V_point', 'Index', size(app.V_selectedPoints, 1));
        end


        function deleteClosest_H(app, x, y)
            pts = app.H_selectedPoints;
            if isempty(pts), return; end

            xlim = app.HAxes.XLim; zlim = app.HAxes.YLim;
            xr = diff(xlim); zr = diff(zlim);
            tol = sqrt( (xr*app.deleteToleranceFactor)^2 + (zr*app.deleteToleranceFactor)^2 );

            d = sqrt( (pts(:,1)-x).^2 + (pts(:,2)-y).^2 );
            [minDist, idx] = min(d);

            if minDist <= tol
                h = app.H_selectedMarkers{idx};
                if isvalid(h), delete(h); end
                app.H_selectedPoints(idx,:) = [];
                app.H_selectedMarkers(idx) = [];
            else
            end
        end

        function deleteClosest_V(app, x, y)
            pts = app.V_selectedPoints;
            if isempty(pts), return; end

            xlim = app.VAxes.XLim; zlim = app.VAxes.YLim;
            xr = diff(xlim); zr = diff(zlim);
            tol = sqrt( (xr*app.deleteToleranceFactor)^2 + (zr*app.deleteToleranceFactor)^2 );

            d = sqrt( (pts(:,1)-x).^2 + (pts(:,2)-y).^2 );
            [minDist, idx] = min(d);

            if minDist <= tol
                h = app.V_selectedMarkers{idx};
                if isvalid(h), delete(h); end
                app.V_selectedPoints(idx,:) = [];
                app.V_selectedMarkers(idx) = [];
            else
            end
        end


        function show_profiles(app, result)

            ix = result.ix;
            iy = result.iy;
            subZ = result.subZ;

            horiz_Z = subZ(result.row, :);
            horiz_X = app.Xvec(ix);

            vert_Z = subZ(:, result.col);
            vert_Y = app.Yvec(iy);

            cla(app.UIAxes, 'reset');
            app.UIAxes.Visible = "off";
            app.HAxes.Visible = "on";
            app.VAxes.Visible = "on";
            app.DrawRectangleButton.Visible = "off";
            app.DoneButton.Visible = "off";
            app.UndoButton.Visible = "on";
            app.DoneButton_2.Visible = "on";

            plot(app.HAxes, horiz_X, horiz_Z, 'LineWidth', 2);
            xlabel(app.HAxes, 'X (µm)');
            ylabel(app.HAxes, 'Z height');
            title(app.HAxes, "Select 6 indices - [lower_boundary, max_z, higher_boundary] for two rings")
            grid(app.HAxes,'on');

            plot(app.VAxes, vert_Y, vert_Z, 'LineWidth', 2);
            xlabel(app.VAxes, 'Y (µm)');
            ylabel(app.VAxes, 'Z height');
            title(app.VAxes, "Select 6 indices - [lower_boundary, max_z, higher_boundary] for two rings")
            grid(app.VAxes,'on');

            start_point_selection(app, horiz_X, horiz_Z, vert_Y, vert_Z);

        end

        function result = nanohole_dimension_analyzer(app, rectPos)

            xmin = rectPos(1);
            ymin = rectPos(2);
            xmax = xmin + rectPos(3);
            ymax = ymin + rectPos(4);

            ix = find(app.Xvec >= xmin & app.Xvec <= xmax);
            iy = find(app.Yvec >= ymin & app.Yvec <= ymax);

            subZ = app.Zgrid(iy, ix);

            [minZ, idx] = min(subZ(:));
            [row, col] = ind2sub(size(subZ), idx);

            minX = app.Xvec(ix(col));
            minY = app.Yvec(iy(row));

            result.minZ  = minZ;
            result.minX  = minX;
            result.minY  = minY;
            result.subZ  = subZ;
            app.minZval = minZ;
            result.ix    = ix;
            result.iy    = iy;
            result.row   = row;
            result.col   = col;

            hold(app.UIAxes,'on');
            plot(app.UIAxes, minX, minY, 'rx', 'LineWidth',2,'MarkerSize',12);
            hold(app.UIAxes,'off');

            show_profiles(app, result);
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnterAFMxyzfileButton
        function EnterAFMxyzfileButtonPushed(app, event)
            try

                [file, location] = uigetfile("*.xyz");
                figure(app.UIFigure)
                app.UIAxes.Visible = "off";
                app.UIAxes.Visible = "on";

                if isequal(file,0)
                    disp('User selected Cancel');
                else
                    app.full_file_path = fullfile(location, file);

                    raw = readmatrix(app.full_file_path, FileType="text", CommentStyle="#");

                    if size(raw,2) < 3
                        errordlg("Invalid XYZ file. It must contain 3 columns: X Y Z");
                        return;
                    end

                    Y = raw(:,1);
                    X = raw(:,2);
                    Z = raw(:,3);

                    ux = unique(X);
                    uy = unique(Y);

                    Nx = numel(ux);
                    Ny = numel(uy);

                    app.Zgrid = reshape(Z, Ny, Nx);
                    app.Zgrid = rot90(app.Zgrid, 1);
                    imagesc(ux, uy, app.Zgrid, "Parent", app.UIAxes);
                    set(app.UIAxes, 'YDir', 'normal');

                    app.Xvec = ux;
                    app.Yvec = uy;

                    axis(app.UIAxes, "image");
                    colormap(app.UIAxes, parula);
                    colorbar(app.UIAxes);

                    app.DoneButton.Visible = "on";
                    app.DoneButton.Enable = "off";
                    app.EnterAFMxyzfileButton.Visible = "off";
                    app.DrawRectangleButton.Visible = "on";

                    app.UIAxes.XLabel.String = "X (m)";
                    app.UIAxes.YLabel.String = "Y (m)";
                    app.UIAxes.Title.String = "AFM Height Map (XYZ) – Draw rectangles around nanoholes";

                    axis(app.UIAxes, "tight");
                end
            catch
                errordlg("Invalid XYZ file. Please select a valid AFM XYZ file.");
            end

        end

        % Button pushed function: DrawRectangleButton
        function DrawRectangleButtonPushed(app, event)
            try
                rect = drawrectangle(app.UIAxes, 'Color', 'r', 'LineWidth', 1.5);

                if isempty(rect) || ~isvalid(rect)

                    return;
                end

                app.drawnRectangles(end+1) = rect;

                app.DoneButton.Enable = "on";
                app.UndoButton.Enable = "on";

                app.rectpositions{end+1} = rect.Position;
                app.nanohole_dimension_analyzer(rect.Position);

            catch ME
                uialert(app.UIFigure, ['Error during rectangle drawing: ' ME.message], 'Drawing Error');
            end
        end

        % Button pushed function: DoneButton_2
        function DoneButton_2Pushed(app, event)
            if size(app.V_selectedPoints, 1) == 6 & size(app.H_selectedPoints, 1) == 6
                [Hx, Hz, Vy, Vz] = app.build_interpolations();

                [~, idx] = min(abs(app.H_interp_z1(1)-app.H_interp_z3));
                [~, idx2] = min(abs(app.H_interp_x3(idx) - app.H_interp_x1));

                app.H_boundaryIndices(1) = idx2;
                
                [~, idx] = min(abs(app.H_interp_z2(end)-app.H_interp_z4));
                [~, idx2] = min(abs(app.H_interp_x4(idx) - app.H_interp_x2));

                app.H_boundaryIndices(2) = idx2;

                [~, idx] = min(abs(app.V_interp_z1(1)-app.V_interp_z3));
                [~, idx2] = min(abs(app.V_interp_y3(idx) - app.V_interp_y1));

                app.V_boundaryIndices(1) = idx2;
                
                [~, idx] = min(abs(app.V_interp_z2(end)-app.V_interp_z4));
                [~, idx2] = min(abs(app.V_interp_y4(idx) - app.V_interp_y2));

                app.V_boundaryIndices(2) = idx2;

                [XL, XR] = app.compute_COM_H();

                DX = abs(XR - XL);       % COM-based diameter
                app.Diameters_COM_X(end+1) = DX;

                % peak-based diameter
                [peakL, peakR] = app.computePeakDiameter_H();
                DX_peak = abs(peakR - peakL);
                app.Diameters_Peak_X(end+1) = DX_peak;

                [YL, YR] = app.compute_COM_V();

                DY = abs(YR - YL);        % COM-based diameter
                app.Diameters_COM_Y(end+1) = DY;

                % peak-based diameter
                [peakL, peakR] = app.computePeakDiameter_V();
                DY_peak = abs(peakR - peakL);
                app.Diameters_Peak_Y(end+1) = DY_peak;

                app.Depth(end+1) = abs(app.minZval)*1e9;

                cla(app.HAxes, "reset");
                cla(app.VAxes, "reset");
                % After all calculations and data storing:
                app.HAxes.Visible = 'off';
                app.VAxes.Visible = 'off';
                

                app.UIAxes.Visible = 'on';
                uistack(app.UIAxes, 'top');


                raw = readmatrix(app.full_file_path, FileType="text", CommentStyle="#");

                if size(raw,2) < 3
                    errordlg("Invalid XYZ file. It must contain 3 columns: X Y Z");
                    return;
                end
     
                Y = raw(:,1);
                X = raw(:,2);
                Z = raw(:,3);

                ux = unique(X);
                uy = unique(Y);

                Nx = numel(ux);
                Ny = numel(uy);

                app.Zgrid = reshape(Z, Ny, Nx);
                app.Zgrid = rot90(app.Zgrid, 1);
                imagesc(ux, uy, app.Zgrid, "Parent", app.UIAxes);
                set(app.UIAxes, 'YDir', 'normal');

                for k = 1:numel(app.rectpositions)
                    pos = app.rectpositions{k};% pos = [x y width height]

                    % Draw rectangle outline (red)
                    rectangle('Position', pos, ...
                        'EdgeColor', 'r', ...
                        'LineWidth', 1.5, ...
                        'Parent', app.UIAxes);
                end

                app.Xvec = ux;
                app.Yvec = uy;

                axis(app.UIAxes, "image");
                colormap(app.UIAxes, parula);
                colorbar(app.UIAxes);

                app.DoneButton_2.Visible = "off";
                app.DoneButton.Enable = "on";
                app.EnterAFMxyzfileButton.Visible = "off";
                app.DrawRectangleButton.Visible = "on";

                app.UIAxes.XLabel.String = "X (m)";
                app.UIAxes.YLabel.String = "Y (m)";
                app.UIAxes.Title.String = "AFM Height Map (XYZ) – Draw rectangles around nanoholes";

                axis(app.UIAxes, "tight");

                app.UndoButton.Visible = "off";
                app.DoneButton.Visible = "on";

                app.H_boundaryIndices = [];
                app.V_boundaryIndices = [];
            end
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            if isempty(app.PointSelectionHistory)
                disp('Nothing to undo for points.');
                return;
            end

            lastEntry = app.PointSelectionHistory{end};
            app.PointSelectionHistory(end) = [];  % pop last

            switch lastEntry.Type
                case 'H_point'
                    idx = lastEntry.Index;
                    if idx <= size(app.H_selectedPoints, 1)
                        h = app.H_selectedMarkers{idx};
                        if isvalid(h), delete(h); end
                        app.H_selectedPoints(idx, :) = [];
                        app.H_selectedMarkers(idx) = [];
                    end

                case 'V_point'
                    idx = lastEntry.Index;
                    if idx <= size(app.V_selectedPoints, 1)
                        h = app.V_selectedMarkers{idx};
                        if isvalid(h), delete(h); end
                        app.V_selectedPoints(idx, :) = [];
                        app.V_selectedMarkers(idx) = [];
                    end
            end

            if size(app.H_selectedPoints, 1) >= 6 && size(app.V_selectedPoints, 1) >= 6
                updateInterpolationPlots(app);
            else

            end

            fig = ancestor(app.UIAxes, 'figure');
            if isempty(fig.WindowButtonMotionFcn) || isempty(fig.WindowButtonDownFcn)
                fig.WindowButtonMotionFcn = @(~,~) app.onMouseMove();
                fig.WindowButtonDownFcn   = @(~,~) app.onMouseClick();
            end

            % Restore hover marker for the axis where the last point was removed
            switch lastEntry.Type
                case 'H_point'
                    if ~isempty(app.H_selectedPoints)
                        lastPt = app.H_selectedPoints(end, :);
                        app.highlight_nearest_H(lastPt(1), lastPt(2));
                    else
                        app.highlight_nearest_H(mean(app.H_profile_X), mean(app.H_profile_Z));
                    end
                case 'V_point'
                    if ~isempty(app.V_selectedPoints)
                        lastPt = app.V_selectedPoints(end, :);
                        app.highlight_nearest_V(lastPt(1), lastPt(2));
                    else
                        app.highlight_nearest_V(mean(app.V_profile_Y), mean(app.V_profile_Z));
                    end
            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
 
            % =========================================================
            % USE INPUT FILE NAME AS BASENAME
            % =========================================================

            % Extract name without extension from app.full_file_path
            [~, baseName, ~] = fileparts(app.full_file_path);

            % Sanitize just in case
            baseName = regexprep(baseName, '[^\w\- ]', '_');

            % Construct output filenames
            imgFileName = baseName + "_nanohole_numbering.png";
            xlsFileName = baseName + "_dimension_analysis.xlsx";

            % =========================================================
            % RESOLVE FULL PATHS (same folder as AFM input)
            % =========================================================
            [inputFolder, ~, ~] = fileparts(app.full_file_path);

            imgFullPath = fullfile(inputFolder, imgFileName);
            xlsFullPath = fullfile(inputFolder, xlsFileName);

            disp("Saving PNG to:  " + imgFullPath);
            disp("Saving XLSX to: " + xlsFullPath);

            % =========================================================
            % RECREATE FIGURE WITH NUMBERED NANOHOLES
            % =========================================================
            fig = figure('Visible','on');  % must be on for proper render

            raw = readmatrix(app.full_file_path, FileType="text", CommentStyle="#");

            Y = raw(:,1);
            X = raw(:,2);
            Z = raw(:,3);

            ux = unique(X);
            uy = unique(Y);

            Nx = numel(ux);
            Ny = numel(uy);

            Zgrid = reshape(Z, Ny, Nx);
            Zgrid = rot90(Zgrid, 1);

            imagesc(ux, uy, Zgrid);
            set(gca,'YDir','normal');
            colormap(parula);
            colorbar;
            axis image;
            xlabel("X (m)");
            ylabel("Y (m)");
            title("Nanoholes with numbering");

            hold on;
            for k = 1:numel(app.rectpositions)
                pos = app.rectpositions{k};
                rectangle('Position', pos, 'EdgeColor','r','LineWidth',1.5);

                xc = pos(1) + pos(3)/2;
                yc = pos(2) + pos(4)/2;
                text(xc, yc, num2str(k),'Color','w','FontSize',14, ...
                    'HorizontalAlignment','center','FontWeight','bold');
            end
            hold off;

            drawnow;
            exportgraphics(fig, imgFullPath, 'Resolution', 300);

            close(fig);

            % ============================================================
            % BUILD DATA TABLE AND WRITE TO EXCEL
            % ============================================================

            nHoles = numel(app.Diameters_COM_X);
            nanoholeNums = (1:nHoles)';

            T = table( ...
                nanoholeNums, ...
                app.Diameters_COM_Y(:)  .* 1e9, ...
                app.Diameters_COM_X(:)  .* 1e9, ...
                app.Diameters_Peak_Y(:) .* 1e9, ...
                app.Diameters_Peak_X(:) .* 1e9, ...
                app.Depth(:), ...
                'VariableNames', { ...
                'NanoholeNumber', ...
                'Diameter_COM_Vertical_nm', ...
                'Diameter_COM_Horizontal_nm', ...
                'Diameter_Peak_Vertical_nm', ...
                'Diameter_Peak_Horizontal_nm', ...
                'Depth' ...
                } ...
                );

            % Round all numeric results (except index column)
            T{:,2:end} = round(T{:,2:end}, 2);

            % Write table to Excel
            writetable(T, xlsFullPath, 'Sheet', 1, 'Range', 'A1');


            % ============================================================
            % WRITE MEAN / STD / MEAN ± STD
            % ============================================================

            statsRow = nHoles + 3;   % Mean row
            stdRow   = nHoles + 4;   % StdDev row
            pmRow    = nHoles + 5;   % Mean ± Std row

            try
                % Try using Excel COM automation (Windows required)
                excel    = actxserver('Excel.Application');
                workbook = excel.Workbooks.Open(xlsFullPath);
                sheet    = workbook.Sheets.Item(1);

                % Row labels
                sheet.Range(['A' num2str(statsRow)]).Value = {'Mean'};
                sheet.Range(['A' num2str(stdRow)]).Value   = {'StdDev'};
                sheet.Range(['A' num2str(pmRow)]).Value    = {'Mean ± Std'};

                % Measurement columns B–F
                columns = {'B','C','D','E','F'};

                for c = 1:numel(columns)
                    col = columns{c};
                    dataRange = sprintf('%s2:%s%d', col, col, nHoles+1);

                    % Excel formulas
                    sheet.Range([col num2str(statsRow)]).Formula = ...
                        sprintf('=ROUND(AVERAGE(%s), 2)', dataRange);

                    sheet.Range([col num2str(stdRow)]).Formula = ...
                        sprintf('=ROUND(STDEV(%s), 2)', dataRange);

                    sheet.Range([col num2str(pmRow)]).Formula = ...
                        sprintf('=TEXT(AVERAGE(%s), "0.00") & " ± " & TEXT(STDEV(%s), "0.00")', ...
                        dataRange, dataRange);
                end

                % Save and close Excel
                workbook.Save();
                workbook.Close();
                excel.Quit();
                excel.delete();

                disp(['Saved Excel with formulas to: ', xlsFullPath]);

            catch
                % Fallback if COM fails (Mac/Linux or no Excel)
                warning('Excel COM unavailable. Writing static mean/std values.');

                means = varfun(@mean, T(:,2:end));
                stds  = varfun(@std,  T(:,2:end));

                % Create "Mean ± Std" text strings
                pmStrings = strcat( ...
                    string(round(means{1,:},2)), ...
                    " ± ", ...
                    string(round(stds{1,:},2)) ...
                    );

                % Matrix to write: Mean row, Std row, Mean±Std row
                statsData = [
                    nan, round(means{1,:},2);
                    nan, round(stds{1,:},2);
                    nan, pmStrings
                    ];

                writematrix(statsData, xlsFullPath, 'Sheet', 1, ...
                    'Range', sprintf('A%d', statsRow));
            end


            % ============================================================
            % RESTART APPLICATION
            % ============================================================

            appClass = class(app);
            delete(app);
            feval(appClass);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.VAxes = uiaxes(app.GridLayout);
            title(app.VAxes, 'Title')
            xlabel(app.VAxes, 'X')
            ylabel(app.VAxes, 'Y')
            zlabel(app.VAxes, 'Z')
            app.VAxes.Visible = 'off';
            app.HAxes = uiaxes(app.GridLayout);
            title(app.HAxes, 'Title')
            xlabel(app.HAxes, 'X')
            ylabel(app.HAxes, 'Y')
            zlabel(app.HAxes, 'Z')
            app.HAxes.Visible = 'off';
            app.DoneButton = uibutton(app.GridLayout, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Visible = 'off';
            app.DoneButton.Text = 'Done';
            app.UndoButton = uibutton(app.GridLayout, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Visible = 'off';
            app.UndoButton.Text = 'Undo';
            app.EnterAFMxyzfileButton = uibutton(app.GridLayout, 'push');
            app.EnterAFMxyzfileButton.ButtonPushedFcn = createCallbackFcn(app, @EnterAFMxyzfileButtonPushed, true);
            app.EnterAFMxyzfileButton.Text = 'Enter AFM .xyz file ';
            app.DrawRectangleButton = uibutton(app.GridLayout, 'push');
            app.DrawRectangleButton.ButtonPushedFcn = createCallbackFcn(app, @DrawRectangleButtonPushed, true);
            app.DrawRectangleButton.Visible = 'off';
            app.DrawRectangleButton.Text = 'Draw Rectangle';
            app.DoneButton_2 = uibutton(app.GridLayout, 'push');
            app.DoneButton_2.ButtonPushedFcn = createCallbackFcn(app, @DoneButton_2Pushed, true);
            app.DoneButton_2.Visible = 'off';
            app.DoneButton_2.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = afmxyznanoholeanalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end